classdef task2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MetricsTextArea                matlab.ui.control.TextArea
        MetricsLabel                   matlab.ui.control.Label
        ResettoDeafultsButton          matlab.ui.control.Button
        SimulateButton                 matlab.ui.control.Button
        AmbientTempKEditField          matlab.ui.control.NumericEditField
        AmbientTempKEditFieldLabel     matlab.ui.control.Label
        StepTimeMinutesEditField       matlab.ui.control.NumericEditField
        StepTimeMinutesEditFieldLabel  matlab.ui.control.Label
        StepInputWEditField            matlab.ui.control.NumericEditField
        StepInputWEditFieldLabel       matlab.ui.control.Label
        R3KWEditField                  matlab.ui.control.NumericEditField
        R3KWEditFieldLabel             matlab.ui.control.Label
        C2JKEditField                  matlab.ui.control.NumericEditField
        C2JKEditFieldLabel             matlab.ui.control.Label
        R2KWEditField                  matlab.ui.control.NumericEditField
        R2KWEditFieldLabel             matlab.ui.control.Label
        C1JKEditField                  matlab.ui.control.NumericEditField
        C1JKEditFieldLabel             matlab.ui.control.Label
        R1KWEditField                  matlab.ui.control.NumericEditField
        R1KWLabel                      matlab.ui.control.Label
        ThermalSystemSimulationLabel   matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
           % Variables
            C1 = app.C1JKEditField.Value;
            C2 = app.C2JKEditField.Value;
            R1 = app.R1KWEditField.Value;
            R2 = app.R2KWEditField.Value;
            R3 = app.R3KWEditField.Value;
            theta_a = app.AmbientTempKEditField.Value;
            qamp = app.StepInputWEditField.Value;
            qtime = app.StepTimeMinutesEditField.Value;

            % Validate all numeric inputs
            if any(isnan([R1, R2, R3, C1, C2, theta_a, qamp, qtime])) || any(isinf([R1, R2, R3, C1, C2, theta_a, qamp, qtime])) 
                uialert(app.UIFigure, ...
                   'Please make sure all values are numeric and not empty.', ...
                   'Invalid Input');
                return;
            end    
            
            % Check for zero resistance 
            if R1 == 0 || R2 == 0 || R3 == 0
               uialert(app.UIFigure, 'Resistance values cannot be zero.', 'Invalid Input');
                 return;
            end

            % Derived resistances
            R12 = (R1 * R2) / (R1 + R2);
            R23 = (R2 * R3) / (R2 + R3);

            % Send variables to Simulink workspace
             assignin('base', 'C1', C1);
             assignin('base', 'C2', C2);
             assignin('base', 'R1', R1);
             assignin('base', 'R2', R2);
             assignin('base', 'R3', R3);
             assignin('base', 'R12', R12);
             assignin('base', 'R23', R23);
             assignin('base','qamp', qamp);
             assignin('base',"qtime", qtime);

            % Simulate the model
            simTime = 7200; % 120 minutes
            simOut = sim('thermal_model', 'StopTime', num2str(simTime));

            %  Extract and plot results
            q1 = simOut.q1.signals.values;
            t = simOut.theta1.time / 60;
            theta1 = simOut.theta1.signals.values + theta_a;
            theta2 = simOut.theta2.signals.values + theta_a;
           
            % Plot on app's UIAxes
            plot(app.UIAxes, t, theta1, 'b', ...
                                 t, theta2, 'r--');
            legend(app.UIAxes, '\theta_1', '\theta_2');
            xlabel(app.UIAxes, 'Time (minutes)');
            ylabel(app.UIAxes, 'Temperature (K)');
            title(app.UIAxes, 'System Response');
            grid(app.UIAxes, 'on');
           
            % After theta plot
            plot(app.UIAxes2, t, q1,  'g', 'LineWidth', 2);
            xlabel(app.UIAxes2, 'Time (minutes)');
            ylabel(app.UIAxes2, 'Heat Flow (W)');
            title(app.UIAxes2, 'Input Heat Flow q_1(t)');
            grid(app.UIAxes2, 'on');

        
            % Calculate performance metrics with 2% settling time threshold
            % Get steady state values
            theta1_ss = theta1(end);
            theta2_ss = theta2(end);

            % Normalize signals for stepinfo 
            theta1_norm = (theta1 - theta1(1)) / (theta1_ss - theta1(1));
            theta2_norm = (theta2 - theta2(1)) / (theta2_ss - theta2(1));

            info1 = stepinfo(theta1_norm, t, 'SettlingTimeThreshold', 0.02);  % 2%
            info2 = stepinfo(theta2_norm, t, 'SettlingTimeThreshold', 0.02);  % 2%

            % Calculate actual settling times after the step input
            settling1_adj = info1.SettlingTime - qtime;
            settling2_adj = info2.SettlingTime - qtime;
            
            % Format results into a neat multi-line message
            msg = {
                                       sprintf('--- Steady-State and Settling Time ---\n');
                    sprintf('Theta1 Steady-State: %.2f K, Settling Time: %.2f minutes\n', theta1_ss, settling1_adj);
                    sprintf('Theta2 Steady-State: %.2f K, Settling Time: %.2f minutes\n', theta2_ss, settling2_adj);
                     };                
            % Display in the app's text area
            app.MetricsTextArea.Value = msg;

  
        end

        % Button pushed function: ResettoDeafultsButton
        function ResetButton(app, event)
            app.R1KWEditField.Value = 10;
            app.R2KWEditField.Value = 10;
            app.R3KWEditField.Value = 10;
            app.C1JKEditField.Value = 50;
            app.C2JKEditField.Value = 60;
            app.AmbientTempKEditField.Value = 293.15;
            app.StepInputWEditField.Value = 3;
            app.StepTimeMinutesEditField.Value = 10;
            app.MetricsTextArea.Value = '';  % clear results
            cla(app.UIAxes);
            cla(app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'System Response')
            xlabel(app.UIAxes, 'Time (minutes)')
            ylabel(app.UIAxes, 'Temperature (K)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Input Heat Flow')
            xlabel(app.UIAxes2, 'Time (minutes)')
            ylabel(app.UIAxes2, 'Heat Flow (W)')
            zlabel(app.UIAxes2, 'Z')
            app.ThermalSystemSimulationLabel = uilabel(app.UIFigure);
            app.ThermalSystemSimulationLabel.Text = 'Thermal System Simulation ';
            app.R1KWLabel = uilabel(app.UIFigure);
            app.R1KWLabel.Text = 'R1 (K/W)';
            app.R1KWEditField = uieditfield(app.UIFigure, 'numeric');
            app.R1KWEditField.Value = 10;
            app.C1JKEditFieldLabel = uilabel(app.UIFigure);
            app.C1JKEditFieldLabel.Text = 'C1 (J/K)';
            app.C1JKEditField = uieditfield(app.UIFigure, 'numeric');
            app.C1JKEditField.Value = 50;
            app.R2KWEditFieldLabel = uilabel(app.UIFigure);
            app.R2KWEditFieldLabel.Text = 'R2 (K/W)';
            app.R2KWEditField = uieditfield(app.UIFigure, 'numeric');
            app.R2KWEditField.Value = 10;
            app.C2JKEditFieldLabel = uilabel(app.UIFigure);
            app.C2JKEditFieldLabel.Text = 'C2 (J/K)';
            app.C2JKEditField = uieditfield(app.UIFigure, 'numeric');
            app.C2JKEditField.Value = 60;
            app.R3KWEditFieldLabel = uilabel(app.UIFigure);
            app.R3KWEditFieldLabel.Text = 'R3 (K/W)';
            app.R3KWEditField = uieditfield(app.UIFigure, 'numeric');
            app.R3KWEditField.Value = 10;
            app.StepInputWEditFieldLabel = uilabel(app.UIFigure);
            app.StepInputWEditFieldLabel.Text = 'Step Input (W)';
            app.StepInputWEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepInputWEditField.Value = 3;
            app.StepTimeMinutesEditFieldLabel = uilabel(app.UIFigure);
            app.StepTimeMinutesEditFieldLabel.Text = 'Step Time (Minutes)';
            app.StepTimeMinutesEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepTimeMinutesEditField.Value = 10;
            app.AmbientTempKEditFieldLabel = uilabel(app.UIFigure);
            app.AmbientTempKEditFieldLabel.Text = 'Ambient Temp (K)';
            app.AmbientTempKEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmbientTempKEditField.ValueDisplayFormat = '%11.5g';
            app.AmbientTempKEditField.Value = 293.15;
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.ResettoDeafultsButton = uibutton(app.UIFigure, 'push');
            app.ResettoDeafultsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButton, true);
            app.ResettoDeafultsButton.Text = 'Reset to Deafults';
            app.MetricsLabel = uilabel(app.UIFigure);
            app.MetricsLabel.Text = 'Metrics';
            app.MetricsTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task2

            % Associate the Simulink Model
            app.Simulation = simulation('thermal_model');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end